classdef PreProcessingSettingsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuroSpikeXPreprocessingSettingsUIFigure  matlab.ui.Figure
        MasterSaveAllSettingsButton    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        TabLoadingAndSmoothing         matlab.ui.container.Tab
        AnalysisDurationEditField      matlab.ui.control.NumericEditField
        AnalysisDurationsecondsLabel   matlab.ui.control.Label
        LoadDataSectionLabel           matlab.ui.control.Label
        NoiseFloorSectionLabel         matlab.ui.control.Label
        SmoothSignalSectionLabel       matlab.ui.control.Label
        ManualThresholdEditField       matlab.ui.control.NumericEditField
        ManualThresholdOverrideoptionalLabel  matlab.ui.control.Label
        PreviewSmoothingButton         matlab.ui.control.Button
        SmoothingFactorEditField       matlab.ui.control.NumericEditField
        SmoothingFactorEditFieldLabel  matlab.ui.control.Label
        LoadedTimepointsListBox        matlab.ui.control.ListBox
        LoadedTimepointsListBoxLabel   matlab.ui.control.Label
        LoadData                       matlab.ui.control.Button
        ThresholdDisplayLabel          matlab.ui.control.Label
        SetThresholdButton             matlab.ui.control.Button
        FixedThresholdCheckbox         matlab.ui.control.CheckBox
        SmoothingAxes4                 matlab.ui.control.UIAxes
        SmoothingAxes3                 matlab.ui.control.UIAxes
        SmoothingAxes2                 matlab.ui.control.UIAxes
        SmoothingAxes1                 matlab.ui.control.UIAxes
        TabSpikeDetection              matlab.ui.container.Tab
        SignalBoundsSectionLabel_2     matlab.ui.control.Label
        SignalBoundsSectionLabel       matlab.ui.control.Label
        PreviewPeaksButton             matlab.ui.control.Button
        CellDropDown                   matlab.ui.control.DropDown
        CellDropDownLabel              matlab.ui.control.Label
        TimepointDropDown              matlab.ui.control.DropDown
        TimepointDropDownLabel         matlab.ui.control.Label
        ProminenceEditField            matlab.ui.control.NumericEditField
        UpperMaxSignalBoundEditField_2Label_2  matlab.ui.control.Label
        MaxBoundEditField              matlab.ui.control.NumericEditField
        MaxBoundLabel_2                matlab.ui.control.Label
        MinBoundEditField              matlab.ui.control.NumericEditField
        MinBoundLabel_2                matlab.ui.control.Label
        UseSignalBoundsCheckbox        matlab.ui.control.CheckBox
        PreviewAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % --- Settings to be saved (correspond to .mat file fields) ---
        n                            double = 60    % Smoothing factor
        use_fixed_threshold          logical = false % Mode for threshold
        signalStrengthThresholdValue double = NaN   % Actual threshold value if use_fixed_threshold is true
        ProminenceValue              double = 1.5   % For findpeaks
        useBounds                    logical = false % Whether to use min/max signal bounds
        minBound                     double = -50   % Lower signal bound
        maxBound                     double = 50    % Upper signal bound
        analysisDurationSeconds      double = 120 % Default to 120 seconds (2 minutes)

        % --- Internal state for UI operation ---
        currentThresholdForPreview   double = 5     % Default/active threshold for UI previews
        
        % --- Loaded Data ---
        fdataByTimepoint             cell           % Cell array of raw fdata matrices {time_row; signal_rows}
        folderPaths                  cell           % Record of loaded folder paths

        % --- Constants ---
        DEFAULT_PREVIEW_THRESHOLD    double = 5
        SETTINGS_FILENAME            char = 'preprocessing_settings.mat'
    end
    
    methods (Access = private)
        
        function updateThresholdDisplay(app)
            % Updates the ThresholdDisplayLabel based on current state
            if app.FixedThresholdCheckbox.Value 
                effective_fixed_thresh = app.signalStrengthThresholdValue;
                if isnan(effective_fixed_thresh) % If fixed but no value set, use preview for display consistency
                    effective_fixed_thresh = app.currentThresholdForPreview;
                end
                app.ThresholdDisplayLabel.Text = sprintf('Fixed Threshold (Preview): %.2f', effective_fixed_thresh);
                if ~isnan(app.ManualThresholdEditField.Value) && app.ManualThresholdEditField.Value > 0 && ...
                   app.ManualThresholdEditField.Value ~= effective_fixed_thresh
                    app.ThresholdDisplayLabel.Text = [app.ThresholdDisplayLabel.Text, sprintf(' (Manual: %.2f)', app.ManualThresholdEditField.Value)];
                end
            else % Auto mode
                app.ThresholdDisplayLabel.Text = sprintf('Auto Threshold (Preview for UI): %.2f', app.currentThresholdForPreview);
            end
        end

        function [processed_signal_info, smoothed_signals, time_vector_raw_matched, time_vector_smoothed] = getProcessedAndSmoothedData(app, timepointIndex)
            processed_signal_info = []; smoothed_signals = []; 
            time_vector_raw_matched = []; time_vector_smoothed = [];

            % Initial checks for fdataByTimepoint
            if isempty(app.fdataByTimepoint) || timepointIndex > numel(app.fdataByTimepoint) || timepointIndex < 1
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Invalid timepoint index or no data loaded.', 'Error'); return;
            end
            fdata_full_matrix_original = app.fdataByTimepoint{timepointIndex};
            if size(fdata_full_matrix_original, 1) < 1
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, sprintf('Data for timepoint %d is empty.', timepointIndex), 'Data Error'); 
                return; 
            end
            
            current_n_val = app.n; % Use the app property 'n'
            current_analysis_duration_sec = app.analysisDurationSeconds; % Get from app property

            try
                % Call ProcessNeuroCa with the desired duration
                processed_signal_info = ProcessNeuroCa(fdata_full_matrix_original, current_analysis_duration_sec);

                if isempty(processed_signal_info) || ~isstruct(processed_signal_info) || ...
                   ~all(isfield(processed_signal_info, {'fdata', 'time', 'analysis_window_frames'})) % Check for NEW field name
                    uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'ProcessNeuroCa output is invalid or missing expected fields.', 'Error'); 
                    processed_signal_info = []; return;
                end
                
                time_vector_raw_matched = processed_signal_info.time;
                if size(processed_signal_info.fdata,2) ~= length(time_vector_raw_matched)
                    if size(processed_signal_info.fdata,2) <= length(time_vector_raw_matched)
                         time_vector_raw_matched = time_vector_raw_matched(1:size(processed_signal_info.fdata,2));
                    else
                        warning('ProcessNeuroCa: fdata timepoints > time vector length. Using indices for raw time.');
                        time_vector_raw_matched = 1:size(processed_signal_info.fdata,2);
                    end
                end

                % --- Call smoothCalciumSignals ---
                smoothed_signals = smoothCalciumSignals(processed_signal_info, current_n_val);
                
                % --- Derive time_vector_smoothed ---
                lag = round((current_n_val - 1) / 2); if lag < 0, lag = 0; end
                f_end_target_from_processed = processed_signal_info.analysis_window_frames;
                num_original_frames_in_fdata_processed = size(processed_signal_info.fdata, 2);

                actual_smooth_signal_cols = size(smoothed_signals, 2);
                time_vector_smoothed = []; 

                if actual_smooth_signal_cols == 0
                    % This happens if smoothCalciumSignals determined num_output_cols = 0
                    time_vector_smoothed = [];
                else
                    % Determine the segment of time_vector_raw_matched that corresponds
                    % to the data that *produced* the smoothed_signals.
                    
                    start_idx_for_output_time = lag + 1; % 1-based index in the *segment that was filtered*
                    end_idx_of_filtered_segment = 0; % End index of the segment that was ACTUALLY filtered

                    if num_original_frames_in_fdata_processed >= (f_end_target_from_processed + lag)
                        % Case 1: Filtered segment was fdata(:, 1:(f_end_target + lag))
                        end_idx_of_filtered_segment = f_end_target_from_processed + lag;
                    else
                        % Case 2: Filtered segment was fdata(:, 1:f_end_target)
                        end_idx_of_filtered_segment = f_end_target_from_processed;
                    end

                    % Ensure indices are valid for time_vector_raw_matched
                    if start_idx_for_output_time > 0 && ...
                       end_idx_of_filtered_segment >= start_idx_for_output_time && ... % End of segment must be >= start of output
                       end_idx_of_filtered_segment <= length(time_vector_raw_matched)

                        % The relevant portion of the original time axis is from where the output starts
                        % up to the end of the segment that was filtered.
                        time_vector_smoothed = time_vector_raw_matched(start_idx_for_output_time : end_idx_of_filtered_segment);
                        
                        % This time_vector_smoothed should now have length equal to actual_smooth_signal_cols.
                        % If not, it means there's still a subtle logic mismatch.
                        if length(time_vector_smoothed) ~= actual_smooth_signal_cols
                            warning(['CRITICAL WARNING: Time axis length still does not match smoothed signal. ',...
                                     'Derived time length: %d, Actual smoothed columns: %d. Defaulting to frame numbers. ',...
                                     'PLEASE DEBUG smoothCalciumSignals output length vs. getProcessedAndSmoothedData time derivation.'], ...
                                     length(time_vector_smoothed), actual_smooth_signal_cols);
                            time_vector_smoothed = 1:actual_smooth_signal_cols;
                        end
                    else
                        warning('Indices for smoothed time axis are out of bounds. Defaulting to frame numbers.');
                        time_vector_smoothed = 1:actual_smooth_signal_cols;
                    end
                end

            catch ME
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, sprintf('Error in getProcessedAndSmoothedData for TP %d:\n%s (Line %d of %s)', timepointIndex, ME.message, ME.stack(1).line, ME.stack(1).name), 'Processing Error');
                processed_signal_info = []; smoothed_signals = []; time_vector_raw_matched = []; time_vector_smoothed = [];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check for saved preprocessing settings
            mainPath = fileparts(mfilename('fullpath'));
            settingsFile = fullfile(mainPath, app.SETTINGS_FILENAME);

            % Initialize app properties with defaults
            app.n = 60;
            app.use_fixed_threshold = false;
            app.signalStrengthThresholdValue = NaN;
            app.currentThresholdForPreview = app.DEFAULT_PREVIEW_THRESHOLD;
            app.ProminenceValue = 1.5;
            app.useBounds = false;
            app.minBound = -50;
            app.maxBound = 50;

            if isfile(settingsFile)
                settings = load(settingsFile);
                if isfield(settings, 'n'), app.n = settings.n; end
                if isfield(settings, 'use_fixed_threshold'), app.use_fixed_threshold = settings.use_fixed_threshold; end
                if isfield(settings, 'signalStrengthThresholdValue'), app.signalStrengthThresholdValue = settings.signalStrengthThresholdValue; end
                if isfield(settings, 'ProminenceValue'), app.ProminenceValue = settings.ProminenceValue; end
                if isfield(settings, 'useBounds'), app.useBounds = settings.useBounds; end
                if isfield(settings, 'minBound'), app.minBound = settings.minBound; end
                if isfield(settings, 'maxBound'), app.maxBound = settings.maxBound; end
                if isfield(settings, 'analysisDurationSeconds') && isnumeric(settings.analysisDurationSeconds) && settings.analysisDurationSeconds > 0
                    app.analysisDurationSeconds = settings.analysisDurationSeconds;
                else
                    app.analysisDurationSeconds = 120; % Default if not found or invalid
                end
            end

            % Populate UI from app properties
            app.AnalysisDurationEditField.Value = app.analysisDurationSeconds;
           
            app.SmoothingFactorEditField.Value = app.n;
            app.FixedThresholdCheckbox.Value = app.use_fixed_threshold;
            
            if app.use_fixed_threshold && ~isnan(app.signalStrengthThresholdValue)
                app.currentThresholdForPreview = app.signalStrengthThresholdValue;
            else
                app.currentThresholdForPreview = app.DEFAULT_PREVIEW_THRESHOLD; % Fallback for preview
            end
            app.ManualThresholdEditField.Value = app.currentThresholdForPreview; % Or app.signalStrengthThresholdValue if appropriate
            
            app.ProminenceEditField.Value = app.ProminenceValue;
            app.UseSignalBoundsCheckbox.Value = app.useBounds;
            app.MinBoundEditField.Value = app.minBound;
            app.MaxBoundEditField.Value = app.maxBound;

            % Enable/disable bounds fields
            app.MinBoundEditField.Enable = app.useBounds;
            app.MaxBoundEditField.Enable = app.useBounds;
            
            app.updateThresholdDisplay();
        end

        % Value changed function: FixedThresholdCheckbox
        function FixedThresholdCheckboxValueChanged(app, event)
            app.use_fixed_threshold = app.FixedThresholdCheckbox.Value;
            app.updateThresholdDisplay();
        end

        % Button pushed function: SetThresholdButton
        function SetThresholdButtonPushed(app, event)
        % Calculate threshold across all loaded timepoints
            if isempty(app.fdataByTimepoint)
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'No data loaded! Please load timepoint data first.', 'Error'); return;
            end
            
            numTimepoints = numel(app.fdataByTimepoint);
            calculated_thresholds_per_timepoint = NaN(1, numTimepoints); % Initialize with NaNs
            
            % uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Calculating thresholds... Please wait.', 'In Progress', 'Icon', 'info', 'Modal', true); % More robust modal alert

            for i = 1:numTimepoints
                try % Outer try for getting processed data
                    [processed_signal_info, smoothed_signals, ~, ~] = app.getProcessedAndSmoothedData(i);
                    
                    if isempty(processed_signal_info) || isempty(smoothed_signals)
                        uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, sprintf('Could not process/smooth data for timepoint %d. Skipping this timepoint.', i), 'Warning');
                        % calculated_thresholds_per_timepoint(i) will remain NaN
                        continue; % Skip to the next timepoint
                    end
                    
                    try % Inner try for SignalStrengthThreshold
                        fprintf('Setting threshold for timepoint %d of %d...\n', i, numTimepoints);
                        threshold_i = SignalStrengthThreshold(smoothed_signals, processed_signal_info.fdata);
                        
                        if isnan(threshold_i) || ~isnumeric(threshold_i)
                             uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, sprintf('Invalid threshold (NaN or non-numeric) returned for timepoint %d. Skipping.',i), 'Warning');
                             % calculated_thresholds_per_timepoint(i) will remain NaN
                        else
                            calculated_thresholds_per_timepoint(i) = threshold_i;
                        end
                    catch ME_sst
                        uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, sprintf('Error during interactive threshold setting for TP %d:\n%s. Skipping this timepoint.', i, ME_sst.message), 'Error');
                        % calculated_thresholds_per_timepoint(i) will remain NaN
                    end % END of inner try-catch for SignalStrengthThreshold

                catch ME_process
                    uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, sprintf('Error processing data for TP %d:\n%s. Skipping this timepoint.', i, ME_process.message), 'Error');
                    % calculated_thresholds_per_timepoint(i) will remain NaN
                end % END of outer try-catch for getProcessedAndSmoothedData
            end % END of for loop
            
            % close(findall(groot, 'Type', 'figure', 'Tag', 'uialert')); % Close any info alerts if used

            valid_thresholds = calculated_thresholds_per_timepoint(~isnan(calculated_thresholds_per_timepoint));
            if isempty(valid_thresholds)
                 uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'No valid thresholds were determined from any timepoint.', 'Calculation Error'); return;
            end

            overall_threshold = ceil(mean(valid_thresholds));
            app.currentThresholdForPreview = overall_threshold;
            app.ManualThresholdEditField.Value = overall_threshold;
            if app.use_fixed_threshold
                 app.signalStrengthThresholdValue = overall_threshold;
            end
            app.updateThresholdDisplay();
            
            msg = sprintf(['Threshold calculation complete.\n' ...
                           'Valid thresholds obtained from %d of %d timepoints.\n' ...
                           'Individual valid values: %s\n' ...
                           'Mean of valid: %.2f\n' ...
                           'Final (ceiled) Preview Threshold: %d'], ...
                           length(valid_thresholds), numTimepoints, mat2str(round(valid_thresholds,2)), mean(valid_thresholds), overall_threshold);
            uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, msg, 'Threshold Calculation Complete','Icon','success');
        end

        % Button pushed function: LoadData
        function LoadDataButtonPushed(app, event)
            app.fdataByTimepoint = {}; 
            app.folderPaths = {};
            app.LoadedTimepointsListBox.Items = {}; 
            app.LoadedTimepointsListBox.Value = {}; % Clear selected value

            maxTimepoints = 10; % Or make this an app property/constant
            
            anyDataLoadedSuccessfully = false; % Flag to track if at least one timepoint loads

            for i = 1:maxTimepoints
                folderPath = uigetdir('', sprintf('Select folder for timepoint #%d (Cancel to stop)', i));
                
                if folderPath == 0 % User pressed Cancel or closed the dialog
                    if i == 1 && isempty(app.fdataByTimepoint) % Cancelled on the very first selection and nothing loaded yet
                        uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'No timepoint folder selected.', 'Info');
                    end
                    break; % Exit the loop
                end
        
                % Define fdataFile INSIDE the loop, AFTER folderPath is obtained
                fdataFile = fullfile(folderPath, 'fdata.mat');
                
                if isfile(fdataFile)
                    try
                        loaded_data = load(fdataFile);
                        varNames = fieldnames(loaded_data);
                        if isempty(varNames)
                            error('fdata.mat is empty or contains no variables.'); % Will be caught
                        end
                        
                        current_fdata_matrix = loaded_data.(varNames{1});
                        if size(current_fdata_matrix,1) < 2 || size(current_fdata_matrix,2) < 1
                             error('fdata.mat must have at least 2 rows (time + 1 signal) and 1 data column.'); % Will be caught
                        end

                        app.fdataByTimepoint{end+1} = current_fdata_matrix;
                        app.folderPaths{end+1} = folderPath;
            
                        folderNameParts = split(folderPath, filesep);
                        timepointLabel = folderNameParts{end}; 
                        app.LoadedTimepointsListBox.Items{end+1} = timepointLabel;
                        
                        if ~anyDataLoadedSuccessfully % If this is the first successfully loaded item
                            app.LoadedTimepointsListBox.Value = timepointLabel; % Select it
                        end
                        anyDataLoadedSuccessfully = true; % Mark that data has been loaded

                    catch ME_load_fdata
                        uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, sprintf('Error loading or processing fdata.mat from %s:\n%s', folderPath, ME_load_fdata.message), 'Load Error');
                    end
                else
                    uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, sprintf('No fdata.mat found in selected folder:\n%s', folderPath), 'Missing File');
                end
            end % End of for loop
        
            if ~anyDataLoadedSuccessfully % Check flag instead of just isempty(app.fdataByTimepoint)
                if i > 1 || (i==1 && folderPath ~=0) % If they made some selections but all failed, or selected one that failed
                     uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'No valid data was loaded from the selected folder(s).', 'Info');
                end
                % Clear dropdowns if no data successfully loaded
                app.TimepointDropDown.Items = {'-No Data-'};
                app.TimepointDropDown.Value = app.TimepointDropDown.Items{1};
                app.CellDropDown.Items = {'-'};
                app.CellDropDown.Value = app.CellDropDown.Items{1};
                return; % Exit if no data loaded
            end

            % Populate timepoint dropdown in Tab 2
            app.TimepointDropDown.Items = app.LoadedTimepointsListBox.Items;
            if ~isempty(app.TimepointDropDown.Items)
                app.TimepointDropDown.Value = app.TimepointDropDown.Items{1}; % Default to first
            else
                app.TimepointDropDown.Items = {'-No Data-'}; % Fallback
                app.TimepointDropDown.Value = app.TimepointDropDown.Items{1};
            end

            % Trigger update for cell dropdown and potentially preview
            % This will also handle the case where TimepointDropDown might be empty
            app.TimepointDropDownValueChanged();
        end

        % Button pushed function: PreviewSmoothingButton
        function PreviewSmoothingButtonPushed(app, event)
            if isempty(app.LoadedTimepointsListBox.Value) || isempty(app.fdataByTimepoint)
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Please load data and select a timepoint.', 'Selection Required'); return;
            end
            timepointLabelSelected = app.LoadedTimepointsListBox.Value;
            timepointIndex = find(strcmp(app.LoadedTimepointsListBox.Items, timepointLabelSelected));
            if isempty(timepointIndex), uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Selected timepoint not found.', 'Error'); return; end

            [processed_signal_info, smoothed_signals, time_vector_raw_matched, time_vector_smoothed] = app.getProcessedAndSmoothedData(timepointIndex);
            if isempty(processed_signal_info) || isempty(smoothed_signals), return; end

            raw_signals_for_plot = processed_signal_info.fdata;
            num_available_cells = size(raw_signals_for_plot, 1);
            if num_available_cells == 0, uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'No cells to display.', 'Info'); return; end
            
            num_axes_to_plot = 4;
            cells_to_plot_indices = randsample(num_available_cells, min(num_axes_to_plot, num_available_cells));
            axes_handles = [app.SmoothingAxes1, app.SmoothingAxes2, app.SmoothingAxes3, app.SmoothingAxes4];
            for k=1:num_axes_to_plot, cla(axes_handles(k)); title(axes_handles(k),''); legend(axes_handles(k),'off'); end

            for i = 1:length(cells_to_plot_indices)
                cell_idx = cells_to_plot_indices(i); ax = axes_handles(i);
                cla(ax); hold(ax, 'on');
                plot(ax, time_vector_raw_matched, raw_signals_for_plot(cell_idx, :), 'k:', 'DisplayName', 'Raw (from ProcessNeuroCa)');
                plot(ax, time_vector_smoothed, smoothed_signals(cell_idx, :), 'r', 'LineWidth', 1.5, 'DisplayName', 'Smoothed');
                hold(ax, 'off'); xlabel(ax, 'Time (s)'); ylabel(ax, 'dF/F');
                title(ax, sprintf("Cell #%d (TP: %s, n=%d)", cell_idx, timepointLabelSelected, app.n));
                legend(ax, 'Location', 'best'); grid(ax, 'on');
            end
        end

        % Button pushed function: MasterSaveAllSettingsButton
        function MasterSaveAllSettingsButtonPushed(app, event)
            mainPath = fileparts(mfilename('fullpath'));
            settingsFile = fullfile(mainPath, app.SETTINGS_FILENAME);
            
            settings = struct();

            % Save timepoint names (tags) in order
            if ~isempty(app.LoadedTimepointsListBox.Items) && ...
               iscell(app.LoadedTimepointsListBox.Items) && ...
               ~any(strcmp(app.LoadedTimepointsListBox.Items, '-No Data-')) % Ensure valid items
                settings.timepointOrderInApp = app.LoadedTimepointsListBox.Items;
            else
                settings.timepointOrderInApp = {}; % Save as empty if no valid timepoints loaded in app
            end

            settings.n = app.n;
            settings.use_fixed_threshold = app.use_fixed_threshold;
            settings.analysisDurationSeconds = app.analysisDurationSeconds;

            if app.use_fixed_threshold
                % app.signalStrengthThresholdValue should be up-to-date from SetThreshold or ManualEdit
                if isnan(app.signalStrengthThresholdValue)
                     uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, ['Fixed Threshold mode is ON, but no valid threshold value is set. ',...
                                           'NaN will be saved. Consider using "Calculate & Set Noise Floor" or entering a manual value.'], ...
                                           'Saving Warning', 'Icon','warning');
                end
                settings.signalStrengthThresholdValue = app.signalStrengthThresholdValue;
            else
                settings.signalStrengthThresholdValue = NaN; 
            end
            
            settings.ProminenceValue = app.ProminenceValue;
            settings.useBounds = app.useBounds; % This is from the checkbox
            settings.minBound = app.minBound;   % This is from the MinBoundEditField's ValueChangedFcn
            settings.maxBound = app.maxBound;   % This is from the MaxBoundEditField's ValueChangedFcn

            try
                save(settingsFile, '-struct', 'settings');
                app.updateThresholdDisplay(); 
                
                saveMsgParts = {sprintf('All settings saved successfully to %s:', settingsFile)};
                saveMsgParts{end+1} = sprintf(' - Smoothing n: %d', settings.n);
                saveMsgParts{end+1} = sprintf(' - Analysis Duration: %d sec', settings.analysisDurationSeconds);
                % ... (add other relevant settings to the message)
                if ~isempty(settings.timepointOrderInApp)
                    saveMsgParts{end+1} = sprintf(' - Timepoint Name Order: %s', strjoin(settings.timepointOrderInApp, ', '));
                end
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, strjoin(saveMsgParts, '\n'), 'Settings Saved','icon','success');

            catch ME_save
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, sprintf('Error saving settings:\n%s', ME_save.message), 'Save Error');
            end
        end

        % Value changed function: UseSignalBoundsCheckbox
        function UseSignalBoundsCheckboxValueChanged(app, event)
            app.useBounds = app.UseSignalBoundsCheckbox.Value;
            app.MinBoundEditField.Enable = app.useBounds; % Show/hide bounds edit field
            app.MaxBoundEditField.Enable = app.useBounds;
        end

        % Value changed function: MaxBoundEditField
        function MaxBoundEditFieldValueChanged(app, event)
            newValue = app.MaxBoundEditField.Value;
            if app.UseSignalBoundsCheckbox.Value && newValue <= app.MinBoundEditField.Value && ~isnan(newValue) && ~isnan(app.MinBoundEditField.Value)
                 uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Max bound must be greater than Min bound.', 'Validation Error');
                 app.MaxBoundEditField.Value = app.maxBound; % Revert to stored app.maxBound
                 return;
            end
            if isnan(newValue)
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Max bound must be a number.', 'Validation Error');
                app.MaxBoundEditField.Value = app.maxBound; return;
            end
            app.maxBound = newValue;
        end

        % Value changed function: MinBoundEditField
        function MinBoundEditFieldValueChanged(app, event)
            newValue = app.MinBoundEditField.Value;
            if app.UseSignalBoundsCheckbox.Value && newValue >= app.MaxBoundEditField.Value && ~isnan(newValue) && ~isnan(app.MaxBoundEditField.Value)
                 uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Min bound must be less than Max bound.', 'Validation Error');
                 app.MinBoundEditField.Value = app.minBound; % Revert to stored app.minBound
                 return;
            end
            if isnan(newValue)
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Min bound must be a number.', 'Validation Error');
                app.MinBoundEditField.Value = app.minBound; return;
            end
            app.minBound = newValue;
        end

        % Button pushed function: PreviewPeaksButton
        function PreviewPeaksButtonPushed(app, event)
            if isempty(app.TimepointDropDown.Items) || strcmp(app.TimepointDropDown.Value, '-No Data-')
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'No timepoint data for peak preview.', 'Missing Data'); return;
            end
            if isempty(app.CellDropDown.Items) || strcmp(app.CellDropDown.Value, '-')
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'No cell selected for peak preview.', 'Missing Data'); return;
            end

            tpLabel = app.TimepointDropDown.Value;
            tpIdx = find(strcmp(app.LoadedTimepointsListBox.Items, tpLabel));
            if isempty(tpIdx), uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Invalid timepoint for peak preview.', 'Error'); return; end

            cellNum = str2double(app.CellDropDown.Value);
            
            [processed_signal_info, smoothed_signals, time_vector_raw_matched, time_vector_smoothed] = app.getProcessedAndSmoothedData(tpIdx);
            if isempty(processed_signal_info) || isempty(smoothed_signals), return; end

            if cellNum > size(smoothed_signals, 1) || cellNum < 1 || cellNum > size(processed_signal_info.fdata,1)
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, sprintf('Invalid cell number %d.', cellNum), 'Index Error'); return;
            end

            raw_trace_plot = processed_signal_info.fdata(cellNum, :);
            smoothed_trace_plot = smoothed_signals(cellNum, :);
            
            % Signal characteristics from smoothed trace
            if isempty(smoothed_trace_plot) % Handle case where smoothing might yield empty
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Smoothed trace is empty for this cell.', 'Data Error'); return;
            end
            min_signal_runtime = min(smoothed_trace_plot); % Min of the smoothed signal for this cell
            max_signal_runtime = max(smoothed_trace_plot);
            signal_strength_runtime = max_signal_runtime - min_signal_runtime;
            
            % --- Use app.currentThresholdForPreview for UI decisions ---
            % This threshold represents the required *signal strength* (max-min)
            threshold_value_for_strength_check = app.currentThresholdForPreview; 
            
            isAboveStrengthThreshold = signal_strength_runtime > threshold_value_for_strength_check;
            
            isWithinBounds = true; 
            if app.UseSignalBoundsCheckbox.Value 
                isWithinBounds = (max_signal_runtime <= app.MaxBoundEditField.Value) && ...
                                 (min_signal_runtime >= app.MinBoundEditField.Value);
            end
            
            if ~(isAboveStrengthThreshold && isWithinBounds)
                exclusion_reason = '';
                if ~isAboveStrengthThreshold, exclusion_reason = [exclusion_reason, sprintf('Signal strength %.2f <= Preview Strength Threshold %.2f. ', signal_strength_runtime, threshold_value_for_strength_check)]; end
                if ~isWithinBounds && app.UseSignalBoundsCheckbox.Value
                    exclusion_reason = [exclusion_reason, sprintf('Smoothed signal (Min:%.2f, Max:%.2f) outside bounds (Min:%.2f, Max:%.2f).', min_signal_runtime, max_signal_runtime, app.MinBoundEditField.Value, app.MaxBoundEditField.Value)];
                end
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, sprintf('Cell %d would be excluded based on current preview settings:\n%s', cellNum, exclusion_reason), 'Preview: Cell Excluded');
                % Decide if you still want to plot or just return. Let's plot to show why.
            end
            
            % Find peaks on the smoothed trace
            [pks, locs] = findpeaks(smoothed_trace_plot, 'MinPeakProminence', app.ProminenceEditField.Value); 
            
            ax_peaks = app.PreviewAxes; 
            cla(ax_peaks); % Clear previous plot
            hold(ax_peaks, 'on'); % Hold on to add multiple plot elements
            
            % Plot raw signal
            plot(ax_peaks, time_vector_raw_matched, raw_trace_plot, 'Color', [0.7 0.7 0.7], 'DisplayName', 'Raw');
            
            % Plot smoothed signal
            plot(ax_peaks, time_vector_smoothed, smoothed_trace_plot, 'b', 'LineWidth', 1.5, 'DisplayName', 'Smoothed');
            
            % Plot peaks
            if ~isempty(pks) && ~isempty(locs)
                plot(ax_peaks, time_vector_smoothed(locs), pks, 'r.', 'MarkerSize', 15, 'DisplayName', 'Peaks');
            end
            
            % Plot Max/Min Bounds if enabled
            if app.UseSignalBoundsCheckbox.Value
                yline(ax_peaks, app.MaxBoundEditField.Value, '--k', 'DisplayName', 'Max Bound'); % Changed legend text
                yline(ax_peaks, app.MinBoundEditField.Value, '--k', 'DisplayName', 'Min Bound'); % Changed legend text
            end
            
            % Plot Noise Threshold Line (Option 1: min_signal + threshold_value)
            % The threshold_value_for_strength_check is the delta (max-min).
            % If you want to visualize a line related to this, it's often plotted as
            % min_smoothed + threshold_delta or some N*std_dev above baseline.
            % For your case "y = min_value + 5", it would be:
            noise_threshold_line_y_value = min_signal_runtime + threshold_value_for_strength_check;
            yline(ax_peaks, noise_threshold_line_y_value, ':m', 'DisplayName', 'Noise Threshold'); % Changed legend text
                                                                                            % Label (text on line) can be added if needed:
                                                                                            % sprintf('Noise Thr. (%.1f)', noise_threshold_line_y_value)

            title(ax_peaks, sprintf('Cell %d - %s (n=%d, P=%.1f)', cellNum, tpLabel, app.n, app.ProminenceEditField.Value));
            xlabel(ax_peaks, 'Time (s)'); 
            ylabel(ax_peaks, 'dF/F');
            
            % Create legend
            legend(ax_peaks, 'Location', 'northoutside', 'Orientation','horizontal');
            
            grid(ax_peaks, 'on');
            hold(ax_peaks, 'off'); % Release hold
        end

        % Value changed function: TimepointDropDown
        function TimepointDropDownValueChanged(app, event)
            if isempty(app.fdataByTimepoint) || strcmp(app.TimepointDropDown.Value, '-No Data-')
                app.CellDropDown.Items = {'-'}; app.CellDropDown.Value = '-'; return; 
            end
            tpLabel = app.TimepointDropDown.Value;
            tpIdx = find(strcmp(app.LoadedTimepointsListBox.Items, tpLabel));
            if isempty(tpIdx), app.CellDropDown.Items = {'-'}; app.CellDropDown.Value = '-'; return; end
            
            current_fdata_full = app.fdataByTimepoint{tpIdx};
            numCells = size(current_fdata_full, 1) - 1;
            if numCells > 0
                app.CellDropDown.Items = arrayfun(@num2str, 1:numCells, 'UniformOutput', false);
                app.CellDropDown.Value = app.CellDropDown.Items{1};
            else
                app.CellDropDown.Items = {'-'}; app.CellDropDown.Value = '-';
            end
            app.PreviewPeaksButtonPushed([]); % Call with empty event data
        end

        % Value changed function: ManualThresholdEditField
        function ManualThresholdEditFieldValueChanged(app, event)
            newValue = app.ManualThresholdEditField.Value;
            if isnan(newValue) || newValue <= 0
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Manual threshold must be a positive number.', 'Invalid Input');
                app.ManualThresholdEditField.Value = app.currentThresholdForPreview; 
                return;
            end
            app.currentThresholdForPreview = newValue;
            if app.use_fixed_threshold % If in fixed mode, manual entry also updates the fixed threshold
                app.signalStrengthThresholdValue = newValue;
            end
            app.updateThresholdDisplay();
        end

        % Value changed function: SmoothingFactorEditField
        function SmoothingFactorEditFieldValueChanged(app, event)
            newValue = app.SmoothingFactorEditField.Value;
            if newValue <= 1 || isnan(newValue)
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Smoothing factor (n) must be a number greater than 1.', 'Invalid Input');
                app.SmoothingFactorEditField.Value = app.n; % Revert to stored app.n
                return;
            end
            app.n = newValue;
        end

        % Value changed function: ProminenceEditField
        function ProminenceEditFieldValueChanged(app, event)
            newValue = app.ProminenceEditField.Value;
            if isnan(newValue) || newValue <= 0
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Prominence must be a positive number.', 'Invalid Input');
                app.ProminenceEditField.Value = app.ProminenceValue; % Revert
                return;
            end
            app.ProminenceValue = newValue;
        end

        % Value changed function: AnalysisDurationEditField
        function AnalysisDurationEditFieldValueChanged(app, event)
            newValue = app.AnalysisDurationEditField.Value;
            if isnan(newValue) || ~isnumeric(newValue) || newValue <= 0
                uialert(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'Analysis duration must be a positive number (in seconds).', 'Invalid Input');
                app.AnalysisDurationEditField.Value = app.analysisDurationSeconds; % Revert to stored value
                return;
            end
            app.analysisDurationSeconds = newValue;
            % No immediate reprocessing needed here, this value will be used when
            % ProcessNeuroCa is called next (via getProcessedAndSmoothedData).
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeuroSpikeXPreprocessingSettingsUIFigure = uifigure('Visible', 'off');
            app.NeuroSpikeXPreprocessingSettingsUIFigure.Name = 'NeuroSpikeX Preprocessing Settings';
            app.TabGroup = uitabgroup(app.NeuroSpikeXPreprocessingSettingsUIFigure);
            app.TabLoadingAndSmoothing = uitab(app.TabGroup);
            app.TabLoadingAndSmoothing.Title = 'Loading, Smoothing, and Noise Floor';
            app.SmoothingAxes1 = uiaxes(app.TabLoadingAndSmoothing);
            xlabel(app.SmoothingAxes1, 'Time (s)')
            ylabel(app.SmoothingAxes1, 'dF/F')
            zlabel(app.SmoothingAxes1, 'Z')
            app.SmoothingAxes2 = uiaxes(app.TabLoadingAndSmoothing);
            xlabel(app.SmoothingAxes2, 'Time (s)')
            ylabel(app.SmoothingAxes2, 'dF/F')
            zlabel(app.SmoothingAxes2, 'Z')
            app.SmoothingAxes3 = uiaxes(app.TabLoadingAndSmoothing);
            xlabel(app.SmoothingAxes3, 'Time (s)')
            ylabel(app.SmoothingAxes3, 'dF/F')
            zlabel(app.SmoothingAxes3, 'Z')
            app.SmoothingAxes4 = uiaxes(app.TabLoadingAndSmoothing);
            xlabel(app.SmoothingAxes4, 'Time (s)')
            ylabel(app.SmoothingAxes4, 'dF/F')
            zlabel(app.SmoothingAxes4, 'Z')
            app.FixedThresholdCheckbox = uicheckbox(app.TabLoadingAndSmoothing);
            app.FixedThresholdCheckbox.ValueChangedFcn = createCallbackFcn(app, @FixedThresholdCheckboxValueChanged, true);
            app.FixedThresholdCheckbox.Text = 'Use Fixed Noise Floor';
            app.SetThresholdButton = uibutton(app.TabLoadingAndSmoothing, 'push');
            app.SetThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @SetThresholdButtonPushed, true);
            app.SetThresholdButton.Text = 'Calculate & Set Noise Floor';
            app.ThresholdDisplayLabel = uilabel(app.TabLoadingAndSmoothing);
            app.ThresholdDisplayLabel.Text = 'Threshold: Auto (Preview: 5)';
            app.LoadData = uibutton(app.TabLoadingAndSmoothing, 'push');
            app.LoadData.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadData.Text = 'Select Timepoint Folder(s)';
            app.LoadedTimepointsListBoxLabel = uilabel(app.TabLoadingAndSmoothing);
            app.LoadedTimepointsListBoxLabel.Text = 'Loaded Timepoints';
            app.LoadedTimepointsListBox = uilistbox(app.TabLoadingAndSmoothing);
            app.LoadedTimepointsListBox.Items = {'Timepoint 1', 'Timepoint 2', 'Timepoint 3', 'Timepoint 4'};
            app.LoadedTimepointsListBox.Value = 'Timepoint 1';
            app.SmoothingFactorEditFieldLabel = uilabel(app.TabLoadingAndSmoothing);
            app.SmoothingFactorEditFieldLabel.Text = 'Smoothing Factor (n):';
            app.SmoothingFactorEditField = uieditfield(app.TabLoadingAndSmoothing, 'numeric');
            app.SmoothingFactorEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingFactorEditFieldValueChanged, true);
            app.SmoothingFactorEditField.Value = 60;
            app.PreviewSmoothingButton = uibutton(app.TabLoadingAndSmoothing, 'push');
            app.PreviewSmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewSmoothingButtonPushed, true);
            app.PreviewSmoothingButton.Text = 'Preview Smoothing';
            app.ManualThresholdOverrideoptionalLabel = uilabel(app.TabLoadingAndSmoothing);
            app.ManualThresholdOverrideoptionalLabel.Text = 'Manual Threshold Override (optional):';
            app.ManualThresholdEditField = uieditfield(app.TabLoadingAndSmoothing, 'numeric');
            app.ManualThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ManualThresholdEditFieldValueChanged, true);
            app.SmoothSignalSectionLabel = uilabel(app.TabLoadingAndSmoothing);
            app.SmoothSignalSectionLabel.Text = '------------------------------------------------ Smooth Normalized Calcium Signals (dF/F) ------------------------------------------------';
            app.NoiseFloorSectionLabel = uilabel(app.TabLoadingAndSmoothing);
            app.NoiseFloorSectionLabel.Text = '---------------------------------------------------------------- Identify Noise Floor ----------------------------------------------------------------';
            app.LoadDataSectionLabel = uilabel(app.TabLoadingAndSmoothing);
            app.LoadDataSectionLabel.Text = '----------------------------------------------------------------------- Load Data -----------------------------------------------------------------------';
            app.AnalysisDurationsecondsLabel = uilabel(app.TabLoadingAndSmoothing);
            app.AnalysisDurationsecondsLabel.Text = 'Analysis Duration (seconds):';
            app.AnalysisDurationEditField = uieditfield(app.TabLoadingAndSmoothing, 'numeric');
            app.AnalysisDurationEditField.ValueDisplayFormat = '%.0f';
            app.AnalysisDurationEditField.ValueChangedFcn = createCallbackFcn(app, @AnalysisDurationEditFieldValueChanged, true);
            app.AnalysisDurationEditField.Value = 120;
            app.TabSpikeDetection = uitab(app.TabGroup);
            app.TabSpikeDetection.Title = 'Spike Detection Parameters';
            app.PreviewAxes = uiaxes(app.TabSpikeDetection);
            title(app.PreviewAxes, 'Peak Detection Preview')
            xlabel(app.PreviewAxes, 'Time (s)')
            ylabel(app.PreviewAxes, 'dF/F')
            zlabel(app.PreviewAxes, 'Z')
            app.UseSignalBoundsCheckbox = uicheckbox(app.TabSpikeDetection);
            app.UseSignalBoundsCheckbox.ValueChangedFcn = createCallbackFcn(app, @UseSignalBoundsCheckboxValueChanged, true);
            app.UseSignalBoundsCheckbox.Text = 'Use Max/Min Signal Bounds';
            app.MinBoundLabel_2 = uilabel(app.TabSpikeDetection);
            app.MinBoundLabel_2.Text = 'Min. Bound:';
            app.MinBoundEditField = uieditfield(app.TabSpikeDetection, 'numeric');
            app.MinBoundEditField.ValueChangedFcn = createCallbackFcn(app, @MinBoundEditFieldValueChanged, true);
            app.MinBoundEditField.Value = -50;
            app.MaxBoundLabel_2 = uilabel(app.TabSpikeDetection);
            app.MaxBoundLabel_2.Text = 'Max. Bound:';
            app.MaxBoundEditField = uieditfield(app.TabSpikeDetection, 'numeric');
            app.MaxBoundEditField.ValueChangedFcn = createCallbackFcn(app, @MaxBoundEditFieldValueChanged, true);
            app.MaxBoundEditField.Value = 50;
            app.UpperMaxSignalBoundEditField_2Label_2 = uilabel(app.TabSpikeDetection);
            app.UpperMaxSignalBoundEditField_2Label_2.Text = 'Min. Peak Prominence (0.5-2)';
            app.ProminenceEditField = uieditfield(app.TabSpikeDetection, 'numeric');
            app.ProminenceEditField.ValueChangedFcn = createCallbackFcn(app, @ProminenceEditFieldValueChanged, true);
            app.ProminenceEditField.Value = 1.5;
            app.TimepointDropDownLabel = uilabel(app.TabSpikeDetection);
            app.TimepointDropDownLabel.Text = 'Timepoint';
            app.TimepointDropDown = uidropdown(app.TabSpikeDetection);
            app.TimepointDropDown.Items = {'-No Data-'};
            app.TimepointDropDown.ValueChangedFcn = createCallbackFcn(app, @TimepointDropDownValueChanged, true);
            app.TimepointDropDown.Value = '-No Data-';
            app.CellDropDownLabel = uilabel(app.TabSpikeDetection);
            app.CellDropDownLabel.Text = 'Cell #';
            app.CellDropDown = uidropdown(app.TabSpikeDetection);
            app.CellDropDown.Items = {'-'};
            app.CellDropDown.Value = '-';
            app.PreviewPeaksButton = uibutton(app.TabSpikeDetection, 'push');
            app.PreviewPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewPeaksButtonPushed, true);
            app.PreviewPeaksButton.Text = 'Preview Peak Detection';
            app.SignalBoundsSectionLabel = uilabel(app.TabSpikeDetection);
            app.SignalBoundsSectionLabel.Text = '----------------------------------------------- (Optional) Upper and Lower Signal Bounds -----------------------------------------------';
            app.SignalBoundsSectionLabel_2 = uilabel(app.TabSpikeDetection);
            app.SignalBoundsSectionLabel_2.Text = '--------------------------------------------------------- Calcium Spike Peak Detection ---------------------------------------------------------';
            app.MasterSaveAllSettingsButton = uibutton(app.NeuroSpikeXPreprocessingSettingsUIFigure, 'push');
            app.MasterSaveAllSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @MasterSaveAllSettingsButtonPushed, true);
            app.MasterSaveAllSettingsButton.Text = 'Save All Settings';
            % Show the figure after all components are created
            app.NeuroSpikeXPreprocessingSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreProcessingSettingsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeuroSpikeXPreprocessingSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuroSpikeXPreprocessingSettingsUIFigure)
        end
    end
end